classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AircraftTab                     matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        InertiaTab                      matlab.ui.container.Tab
        MassEditFieldLabel              matlab.ui.control.Label
        MassEditField                   matlab.ui.control.NumericEditField
        IxxEditFieldLabel               matlab.ui.control.Label
        IxxEditField                    matlab.ui.control.NumericEditField
        IyyEditFieldLabel               matlab.ui.control.Label
        IyyEditField                    matlab.ui.control.NumericEditField
        IzzEditFieldLabel               matlab.ui.control.Label
        IzzEditField                    matlab.ui.control.NumericEditField
        IxzEditFieldLabel               matlab.ui.control.Label
        IxzEditField                    matlab.ui.control.NumericEditField
        IxyEditFieldLabel               matlab.ui.control.Label
        IxyEditField                    matlab.ui.control.NumericEditField
        IyzEditFieldLabel               matlab.ui.control.Label
        IyzEditField                    matlab.ui.control.NumericEditField
        AircraftMomentofInertiaskgm2Label  matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        GeometryTab                     matlab.ui.container.Tab
        PlanformAreaEditFieldLabel      matlab.ui.control.Label
        PlanformAreaEditField           matlab.ui.control.NumericEditField
        WingSpanEditFieldLabel          matlab.ui.control.Label
        WingSpanEditField               matlab.ui.control.NumericEditField
        ChordLengthEditFieldLabel       matlab.ui.control.Label
        ChordLengthEditField            matlab.ui.control.NumericEditField
        m2Label                         matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        PropulsionTab                   matlab.ui.container.Tab
        MaximumPowerEditFieldLabel      matlab.ui.control.Label
        MaximumPowerEditField           matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel        matlab.ui.control.Label
        EfficiencyEditField             matlab.ui.control.NumericEditField
        WLabel                          matlab.ui.control.Label
        ControlSurfacesTab              matlab.ui.container.Tab
        ElevatorUpperLimitEditFieldLabel  matlab.ui.control.Label
        ElevatorUpperLimitEditField     matlab.ui.control.NumericEditField
        ElevatorLowerLimitEditFieldLabel  matlab.ui.control.Label
        ElevatorLowerLimitEditField     matlab.ui.control.NumericEditField
        AileronUpperLimitEditFieldLabel  matlab.ui.control.Label
        AileronUpperLimitEditField      matlab.ui.control.NumericEditField
        AileronLowerLimitEditFieldLabel  matlab.ui.control.Label
        AileronLowerLimitEditField      matlab.ui.control.NumericEditField
        RudderLowerLimitEditFieldLabel  matlab.ui.control.Label
        RudderLowerLimitEditField       matlab.ui.control.NumericEditField
        RudderUpperLimitEditFieldLabel  matlab.ui.control.Label
        RudderUpperLimitEditField       matlab.ui.control.NumericEditField
        AerodynamicsTab                 matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        LongitudinalTab                 matlab.ui.container.Tab
        LiftLabel                       matlab.ui.control.Label
        CL_0EditFieldLabel              matlab.ui.control.Label
        CL_0EditField                   matlab.ui.control.NumericEditField
        CL_alphaEditFieldLabel          matlab.ui.control.Label
        CL_alphaEditField               matlab.ui.control.NumericEditField
        CL_qEditFieldLabel              matlab.ui.control.Label
        CL_qEditField                   matlab.ui.control.NumericEditField
        DragLabel                       matlab.ui.control.Label
        CD_0EditFieldLabel              matlab.ui.control.Label
        CD_0EditField                   matlab.ui.control.NumericEditField
        kEditFieldLabel                 matlab.ui.control.Label
        kEditField                      matlab.ui.control.NumericEditField
        PitchMomentLabel                matlab.ui.control.Label
        Cm_0EditFieldLabel              matlab.ui.control.Label
        Cm_0EditField                   matlab.ui.control.NumericEditField
        Cm_qEditFieldLabel              matlab.ui.control.Label
        Cm_qEditField                   matlab.ui.control.NumericEditField
        Cm_alphaEditFieldLabel          matlab.ui.control.Label
        Cm_alphaEditField               matlab.ui.control.NumericEditField
        CL_alphadotEditFieldLabel       matlab.ui.control.Label
        CL_alphadotEditField            matlab.ui.control.NumericEditField
        CL_delevatorEditFieldLabel      matlab.ui.control.Label
        CL_delevatorEditField           matlab.ui.control.NumericEditField
        Cm_alphadotEditFieldLabel       matlab.ui.control.Label
        Cm_alphadotEditField            matlab.ui.control.NumericEditField
        Cm_delevatorEditFieldLabel      matlab.ui.control.Label
        Cm_delevatorEditField           matlab.ui.control.NumericEditField
        LateralTab                      matlab.ui.container.Tab
        SideForceLabel                  matlab.ui.control.Label
        RollMomentLabel                 matlab.ui.control.Label
        YawMomentLabel                  matlab.ui.control.Label
        CY_0EditFieldLabel              matlab.ui.control.Label
        CY_0EditField                   matlab.ui.control.NumericEditField
        CY_betaEditFieldLabel           matlab.ui.control.Label
        CY_betaEditField                matlab.ui.control.NumericEditField
        CY_pEditFieldLabel              matlab.ui.control.Label
        CY_pEditField                   matlab.ui.control.NumericEditField
        Cl_0EditFieldLabel              matlab.ui.control.Label
        Cl_0EditField                   matlab.ui.control.NumericEditField
        Cl_betaEditFieldLabel           matlab.ui.control.Label
        Cl_betaEditField                matlab.ui.control.NumericEditField
        Cl_betadotEditFieldLabel        matlab.ui.control.Label
        Cl_betadotEditField             matlab.ui.control.NumericEditField
        Cn_0EditFieldLabel              matlab.ui.control.Label
        Cn_0EditField                   matlab.ui.control.NumericEditField
        Cn_betadotEditFieldLabel        matlab.ui.control.Label
        Cn_betadotEditField             matlab.ui.control.NumericEditField
        Cn_betaEditFieldLabel           matlab.ui.control.Label
        Cn_betaEditField                matlab.ui.control.NumericEditField
        CY_rEditFieldLabel              matlab.ui.control.Label
        CY_rEditField                   matlab.ui.control.NumericEditField
        CY_daileronEditFieldLabel       matlab.ui.control.Label
        CY_daileronEditField            matlab.ui.control.NumericEditField
        CY_drudderEditFieldLabel        matlab.ui.control.Label
        CY_drudderEditField             matlab.ui.control.NumericEditField
        CY_betadotEditFieldLabel        matlab.ui.control.Label
        CY_betadotEditField             matlab.ui.control.NumericEditField
        Cl_pEditFieldLabel              matlab.ui.control.Label
        Cl_pEditField                   matlab.ui.control.NumericEditField
        Cl_rEditFieldLabel              matlab.ui.control.Label
        Cl_rEditField                   matlab.ui.control.NumericEditField
        Cl_daileronEditFieldLabel       matlab.ui.control.Label
        Cl_daileronEditField            matlab.ui.control.NumericEditField
        Cl_drudderEditFieldLabel        matlab.ui.control.Label
        Cl_drudderEditField             matlab.ui.control.NumericEditField
        Cn_pEditFieldLabel              matlab.ui.control.Label
        Cn_pEditField                   matlab.ui.control.NumericEditField
        Cn_rEditFieldLabel              matlab.ui.control.Label
        Cn_rEditField                   matlab.ui.control.NumericEditField
        Cn_daileronEditFieldLabel       matlab.ui.control.Label
        Cn_daileronEditField            matlab.ui.control.NumericEditField
        Cn_drudderEditFieldLabel        matlab.ui.control.Label
        Cn_drudderEditField             matlab.ui.control.NumericEditField
        OtherTab                        matlab.ui.container.Tab
        CentreofGravityDuringMeasurementsEditFieldLabel  matlab.ui.control.Label
        CentreofGravityDuringMeasurementsEditField  matlab.ui.control.NumericEditField
        perunitMACLabel                 matlab.ui.control.Label
        OptionsTab                      matlab.ui.container.Tab
        AircraftFileDropDownLabel       matlab.ui.control.Label
        AircraftFileDropDown            matlab.ui.control.DropDown
        loadaircraftButton              matlab.ui.control.Button
        saveaircraftButton              matlab.ui.control.Button
        AircraftNameEditFieldLabel      matlab.ui.control.Label
        AircraftNameEditField           matlab.ui.control.EditField
        AircraftDescriptionTextAreaLabel  matlab.ui.control.Label
        AircraftDescriptionTextArea     matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        ProfileImageFolderButton        matlab.ui.control.Button
        AircraftClassDropDownLabel      matlab.ui.control.Label
        AircraftClassDropDown           matlab.ui.control.DropDown
        EnvironmentTab                  matlab.ui.container.Tab
        gravityEditFieldLabel           matlab.ui.control.Label
        gravityEditField                matlab.ui.control.NumericEditField
        WindSpeedLabel                  matlab.ui.control.Label
        WindSpeedEditField              matlab.ui.control.NumericEditField
        SeaLevelDensityEditFieldLabel   matlab.ui.control.Label
        SeaLevelDensityEditField        matlab.ui.control.NumericEditField
        SeaLevelTemperatureEditFieldLabel  matlab.ui.control.Label
        SeaLevelTemperatureEditField    matlab.ui.control.NumericEditField
        LapseRateEditFieldLabel         matlab.ui.control.Label
        LapseRateEditField              matlab.ui.control.NumericEditField
        UniversalGasConstantEditFieldLabel  matlab.ui.control.Label
        UniversalGasConstantEditField   matlab.ui.control.NumericEditField
        WindBearingEditFieldLabel       matlab.ui.control.Label
        WindBearingEditField            matlab.ui.control.NumericEditField
        SealLevelPressureEditFieldLabel  matlab.ui.control.Label
        SealLevelPressureEditField      matlab.ui.control.NumericEditField
        OperationTab                    matlab.ui.container.Tab
        TrimmedAltitudeEditFieldLabel   matlab.ui.control.Label
        TrimmedAltitudeEditField        matlab.ui.control.NumericEditField
        CentreofGravityPositionEditFieldLabel  matlab.ui.control.Label
        CentreofGravityPositionEditField  matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        BuiltinOperationsButton         matlab.ui.control.RadioButton
        ManualControlsButton            matlab.ui.control.RadioButton
        TabGroup4                       matlab.ui.container.TabGroup
        BuiltinOperationsTab            matlab.ui.container.Tab
        OperationNameDropDownLabel      matlab.ui.control.Label
        OperationNameDropDown           matlab.ui.control.DropDown
        ManualControlsTab               matlab.ui.container.Tab
        ImportControlsButton            matlab.ui.control.Button
        CalibrateButtonGroup            matlab.ui.container.ButtonGroup
        RelativetoTrimButton            matlab.ui.control.RadioButton
        TrimmedBearingEditFieldLabel    matlab.ui.control.Label
        TrimmedBearingEditField         matlab.ui.control.NumericEditField
        TrimmedAirspeedEditFieldLabel   matlab.ui.control.Label
        TrimmedAirspeedEditField        matlab.ui.control.NumericEditField
        TrimmedSideslipAngleEditFieldLabel  matlab.ui.control.Label
        TrimmedSideslipAngleEditField   matlab.ui.control.NumericEditField
        FlightPhaseCategoryDropDownLabel  matlab.ui.control.Label
        FlightPhaseCategoryDropDown     matlab.ui.control.DropDown
        SimulationTab                   matlab.ui.container.Tab
        RUNButton                       matlab.ui.control.Button
        TimeSpanLabel                   matlab.ui.control.Label
        TimeSpanEditField               matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel          matlab.ui.control.Label
        TimeStepEditField               matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        PlotTrajectoryRequiresthemappingtoolboxCheckBox  matlab.ui.control.CheckBox
        AnimateRequirestheaerospacetoolboxCheckBox  matlab.ui.control.CheckBox
        ResultsTab                      matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        TransientResponseTab            matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        LongitudinalTab_2               matlab.ui.container.Tab
        UIAxes_1                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        LateralTab_2                    matlab.ui.container.Tab
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_10                       matlab.ui.control.UIAxes
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_12                       matlab.ui.control.UIAxes
        AerodynamicFrameTab             matlab.ui.container.Tab
        UIAxes_13                       matlab.ui.control.UIAxes
        UIAxes_14                       matlab.ui.control.UIAxes
        UIAxes_15                       matlab.ui.control.UIAxes
        ControlsTab                     matlab.ui.container.Tab
        UIAxes_16                       matlab.ui.control.UIAxes
        UIAxes_17                       matlab.ui.control.UIAxes
        UIAxes_18                       matlab.ui.control.UIAxes
        UIAxes_19                       matlab.ui.control.UIAxes
        LinearAnalysisTab               matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        DynamicMatriciesTab             matlab.ui.container.Tab
        UITableA                        matlab.ui.control.Table
        UITableB                        matlab.ui.control.Table
        EigenAnalysisTab                matlab.ui.container.Tab
        UITableV                        matlab.ui.control.Table
        UITableE                        matlab.ui.control.Table
        UITableWnZ                      matlab.ui.control.Table
        ArgandDiagramsTab_2             matlab.ui.container.Tab
        TabGroup8                       matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        UIAxes_20                       matlab.ui.control.UIAxes
        Tab_2                           matlab.ui.container.Tab
        UIAxes_21                       matlab.ui.control.UIAxes
        Tab_3                           matlab.ui.container.Tab
        UIAxes_22                       matlab.ui.control.UIAxes
        Tab_4                           matlab.ui.container.Tab
        UIAxes_23                       matlab.ui.control.UIAxes
        Tab_5                           matlab.ui.container.Tab
        UIAxes_24                       matlab.ui.control.UIAxes
        Tab_6                           matlab.ui.container.Tab
        UIAxes_25                       matlab.ui.control.UIAxes
        Tab_7                           matlab.ui.container.Tab
        UIAxes_26                       matlab.ui.control.UIAxes
        Tab_8                           matlab.ui.container.Tab
        UIAxes_27                       matlab.ui.control.UIAxes
        Tab_9                           matlab.ui.container.Tab
        UIAxes_28                       matlab.ui.control.UIAxes
        Tab_10                          matlab.ui.container.Tab
        UIAxes_29                       matlab.ui.control.UIAxes
        MilitarySpecificationsTab       matlab.ui.container.Tab
        TabGroup9                       matlab.ui.container.TabGroup
        SPWnvszetaTab                   matlab.ui.container.Tab
        UIAxes_30                       matlab.ui.control.UIAxes
        SPWnvsdndalphaTab               matlab.ui.container.Tab
        UIAxes_31                       matlab.ui.control.UIAxes
        DRTab                           matlab.ui.container.Tab
        UIAxes_32                       matlab.ui.control.UIAxes
        OptionsTab_2                    matlab.ui.container.Tab
        Includethecontroldesignerfromaero3000Label  matlab.ui.control.Label
        CompareResultsLabel             matlab.ui.control.Label
        RealTimevisualsimulationLabel   matlab.ui.control.Label
        LoadSaveAllDataexceptresultsLabel  matlab.ui.control.Label
        FileDropDownLabel               matlab.ui.control.Label
        FileDropDown                    matlab.ui.control.DropDown
        LoadFileButton                  matlab.ui.control.Button
        SaveFileButton                  matlab.ui.control.Button
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function aircraft = aircraftStructure(app)
            
            aircraft.x_cg_0         = app.CentreofGravityDuringMeasurementsEditField.Value;
            
            aircraft.Inertia.m      = app.MassEditField.Value;
            aircraft.Inertia.Ixx    = app.IxxEditField.Value;
            aircraft.Inertia.Iyy    = app.IyyEditField.Value;
            aircraft.Inertia.Izz    = app.IzzEditField.Value;
            aircraft.Inertia.Ixy    = app.IxyEditField.Value;
            aircraft.Inertia.Ixz    = app.IxzEditField.Value;
            aircraft.Inertia.Iyz    = app.IyzEditField.Value;
            
            aircraft.Geom.S         = app.PlanformAreaEditField.Value;
            aircraft.Geom.b         = app.WingSpanEditField.Value;
            aircraft.Geom.c         = app.ChordLengthEditField.Value;
            
            aircraft.Prop.P_max     = app.MaximumPowerEditField.Value;
            aircraft.Prop.eta       = app.EfficiencyEditField.Value;
            
            aircraft.CtrlLim.Lwr = [0;
                                    app.ElevatorLowerLimitEditField.Value;
                                    app.AileronLowerLimitEditField.Value;
                                    app.RudderLowerLimitEditField.Value];
            aircraft.CtrlLim.Upr = [1;
                                    app.ElevatorUpperLimitEditField.Value;
                                    app.AileronUpperLimitEditField.Value;
                                    app.RudderUpperLimitEditField.Value];
            
            aircraft.Aero.CLo       = app.CL_0EditField.Value;
            aircraft.Aero.CLa       = app.CL_alphaEditField.Value;
            aircraft.Aero.CLad      = app.CL_alphadotEditField.Value;
            aircraft.Aero.CLq       = app.CL_qEditField.Value;
            aircraft.Aero.CLde      = app.CL_delevatorEditField.Value;
            
            aircraft.Aero.CDo       = app.CD_0EditField.Value;
            aircraft.Aero.k         = app.kEditField.Value;
            
            aircraft.Aero.Cmo       = app.Cm_0EditField.Value;
            aircraft.Aero.Cma       = app.Cm_alphaEditField.Value;
            aircraft.Aero.Cmad      = app.Cm_alphadotEditField.Value;
            aircraft.Aero.Cmq       = app.Cm_qEditField.Value;
            aircraft.Aero.Cmde      = app.Cm_delevatorEditField.Value;
            
            aircraft.Aero.CYo       = app.CY_0EditField.Value;
            aircraft.Aero.CYb       = app.CY_betaEditField.Value;
            aircraft.Aero.CYbd      = app.CY_betadotEditField.Value;
            aircraft.Aero.CYp       = app.CY_pEditField.Value;
            aircraft.Aero.CYr       = app.CY_rEditField.Value;
            aircraft.Aero.CYda      = app.CY_daileronEditField.Value;
            aircraft.Aero.CYdr      = app.CY_drudderEditField.Value;
            
            aircraft.Aero.Clo       = app.Cl_0EditField.Value;
            aircraft.Aero.Clb       = app.Cl_betaEditField.Value;
            aircraft.Aero.Clbd      = app.Cl_betadotEditField.Value;
            aircraft.Aero.Clp       = app.Cl_pEditField.Value;
            aircraft.Aero.Clr       = app.Cl_rEditField.Value;
            aircraft.Aero.Clda      = app.Cl_daileronEditField.Value;
            aircraft.Aero.Cldr      = app.Cl_drudderEditField.Value;
            
            aircraft.Aero.Cno       = app.Cn_0EditField.Value;
            aircraft.Aero.Cnb       = app.Cn_betaEditField.Value;
            aircraft.Aero.Cnbd      = app.Cn_betadotEditField.Value;
            aircraft.Aero.Cnp       = app.Cn_pEditField.Value;
            aircraft.Aero.Cnr       = app.Cn_rEditField.Value;
            aircraft.Aero.Cnda      = app.Cn_daileronEditField.Value;
            aircraft.Aero.Cndr      = app.Cn_drudderEditField.Value;
            
            aircraft.Options.name   = app.AircraftNameEditField.Value;
            aircraft.Options.description = app.AircraftDescriptionTextArea.Value;
            aircraft.Options.profile = app.Image.ImageSource;
            
        end
        
        function aircraftEditFields(app, aircraft)
            
            app.CentreofGravityDuringMeasurementsEditField.Value = aircraft.x_cg_0;
            
            app.MassEditField.Value                 = aircraft.Inertia.m;
            app.IxxEditField.Value                  = aircraft.Inertia.Ixx;
            app.IyyEditField.Value                  = aircraft.Inertia.Iyy;
            app.IzzEditField.Value                  = aircraft.Inertia.Izz;
            app.IxyEditField.Value                  = aircraft.Inertia.Ixy;
            app.IxzEditField.Value                  = aircraft.Inertia.Ixz;
            app.IyzEditField.Value                  = aircraft.Inertia.Iyz;
            
            app.PlanformAreaEditField.Value         = aircraft.Geom.S;
            app.WingSpanEditField.Value             = aircraft.Geom.b;
            app.ChordLengthEditField.Value          = aircraft.Geom.c;
            
            app.MaximumPowerEditField.Value         = aircraft.Prop.P_max;
            app.EfficiencyEditField.Value           = aircraft.Prop.eta;
            
            app.ElevatorUpperLimitEditField.Value   = aircraft.CtrlLim.Upr(2);
            app.ElevatorLowerLimitEditField.Value   = aircraft.CtrlLim.Lwr(2);
            app.AileronUpperLimitEditField.Value    = aircraft.CtrlLim.Upr(3);
            app.AileronLowerLimitEditField.Value    = aircraft.CtrlLim.Lwr(3);
            app.RudderUpperLimitEditField.Value     = aircraft.CtrlLim.Upr(4);
            app.RudderLowerLimitEditField.Value     = aircraft.CtrlLim.Lwr(4);
            
            app.CL_0EditField.Value                 = aircraft.Aero.CLo;
            app.CL_alphaEditField.Value             = aircraft.Aero.CLa;
            app.CL_alphadotEditField.Value          = aircraft.Aero.CLad;
            app.CL_qEditField.Value                 = aircraft.Aero.CLq;
            app.CL_delevatorEditField.Value         = aircraft.Aero.CLde;
            
            app.CD_0EditField.Value                 = aircraft.Aero.Cdo;
            app.kEditField.Value                    = aircraft.Aero.k;
            
            app.Cm_0EditField.Value                 = aircraft.Aero.Cmo;
            app.Cm_alphaEditField.Value             = aircraft.Aero.Cma;
            app.Cm_alphadotEditField.Value          = aircraft.Aero.Cmad;
            app.Cm_qEditField.Value                 = aircraft.Aero.Cmq;
            app.Cm_delevatorEditField.Value         = aircraft.Aero.Cmde;
            
            app.CY_0EditField.Value                 = aircraft.Aero.Cyo;
            app.CY_betaEditField.Value              = aircraft.Aero.Cyb;
            app.CY_betadotEditField.Value           = aircraft.Aero.Cybd;
            app.CY_pEditField.Value                 = aircraft.Aero.Cyp;
            app.CY_rEditField.Value                 = aircraft.Aero.Cyr;
            app.CY_daileronEditField.Value          = aircraft.Aero.Cyda;
            app.CY_drudderEditField.Value           = aircraft.Aero.Cydr;
            
            app.Cl_0EditField.Value                 = aircraft.Aero.Clo;
            app.Cl_betaEditField.Value              = aircraft.Aero.Clb;
            app.Cl_betadotEditField.Value           = aircraft.Aero.Clbd;
            app.Cl_pEditField.Value                 = aircraft.Aero.Clp;
            app.Cl_rEditField.Value                 = aircraft.Aero.Clr;
            app.Cl_daileronEditField.Value          = aircraft.Aero.Clda;
            app.Cl_drudderEditField.Value           = aircraft.Aero.Cldr;
            
            app.Cn_0EditField.Value                 = aircraft.Aero.Cno;
            app.Cn_betaEditField.Value              = aircraft.Aero.Cnb;
            app.Cn_betadotEditField.Value           = aircraft.Aero.Cnbd;
            app.Cn_pEditField.Value                 = aircraft.Aero.Cnp;
            app.Cn_rEditField.Value                 = aircraft.Aero.Cnr;
            app.Cn_daileronEditField.Value          = aircraft.Aero.Cnda;
            app.Cn_drudderEditField.Value           = aircraft.Aero.Cnr;
            
            app.AircraftNameEditField.Value         = aircraft.Options.name;
            app.AircraftDescriptionTextArea.Value   = aircraft.Options.description;
            app.Image.ImageSource                   = aircraft.Options.profile;
            
        end
        
        function parameters = parametersStructure(app)
            
            aircraft = aircraftStructure(app);
            
            operation.name                      = app.OperationNameDropDown.Value;
            operation.Trim.airspeed             = app.TrimmedAirspeedEditField.Value;
            operation.Trim.bearing              = app.TrimmedBearingEditField.Value;
            operation.Trim.altitude             = app.TrimmedAltitudeEditField.Value;
            operation.Trim.beta                 = app.TrimmedSideslipAngleEditField.Value;
            operation.x_cg                      = app.CentreofGravityPositionEditField.Value;
            
            environment.gravity                 = app.gravityEditField.Value;
            environment.Wind.speed              = app.WindSpeedEditField.Value;
            environment.Wind.bearing            = app.WindBearingEditField.Value;
            environment.Sealevel.density        = app.SeaLevelDensityEditField.Value;
            environment.Sealevel.temperature    = app.SeaLevelTemperatureEditField.Value;
            environment.Sealevel.pressure       = app.SealLevelPressureEditField.Value;
            environment.lapseRate               = app.LapseRateEditField.Value;
            environment.universalGasConst       = app.UniversalGasConstantEditField.Value;
            
            simulation.timeSpan                 = app.TimeSpanEditField.Value;
            simulation.timeStep                 = app.TimeStepEditField.Value;
            
            
            % Package output
            parameters.aircraft     = aircraft;
            parameters.operation    = operation;
            parameters.environment  = environment;
            parameters.simulation   = simulation;
            
        end
        
        function parametersEditFields(app, parameters)
            
            % Unpack
            aircraft    = parameters.aircraft;
            operation   = parameters.operation;
            environment = parameters.environment;
            simulation  = parameters.simulation;
            
            
            aircraftEditFields(app, aircraft);
            
            app.OperationNameDropDown.Value         = operation.name;
            app.TrimmedAirspeedDropDown.Value       = operation.Trim.airspeed;
            app.TrimmedBearingEditField.Value       = operation.Trim.bearing;
            app.TrimmedAltitudeEditField.Value      = app.TrimmedAltitudeEditField.Value;
            app.TrimmedSideslipAngleEditField.Value = operation.Trim.beta;
            app.CentreofGravityPositionEditField.Value = operation.x_cg;
            
            app.gravityEditField.Value              = environment.gravity;
            app.WindSpeedEditField.Value            = environment.windSpeed;
            app.WindBearingEditField.Value          = environment.windBearing;
            app.SeaLevelDensityEditField.Value      = environment.sealevel.density;
            app.SeaLevelTemperatureEditField.Value  = environment.sealevel.temperature;
            app.SealLevelPressureEditField.Value    = environment.Sealevel.pressure;
            app.LapseRateEditField.Value            = environment.lapseRate;
            app.UniversalGasConstantEditField.Value = environment.universalGasConst;
            
            app.TimeSpanEditField.Value             = simulation.timeSpan;
            app.TimeStepEditField.Value             = simulation.timeStep;
        end
        
        function Results_TransientResponse(app, Transient)
            
            %% Unpack
            T       = Transient.T;
            Xlin    = Transient.X_lin;
            Xnl     = Transient.X_nl;
            U       = Transient.U;
            
            % Units
            deg = 180/pi;                   % rad to deg
            kts = 1.944;                    % m/s to knots
            ft = 3.2808399;                 % m to ft
            
            %% Unpack Input
            u   = [Xlin(1,:); Xnl(1,:)];
            v   = [Xlin(2,:); Xnl(2,:)];
            w   = [Xlin(3,:); Xnl(3,:)];
            p   = [Xlin(4,:); Xnl(4,:)];
            q   = [Xlin(5,:); Xnl(5,:)];
            r   = [Xlin(6,:); Xnl(6,:)];
            phi = [Xlin(7,:); Xnl(7,:)];
           theta= [Xlin(8,:); Xnl(8,:)];
            psi = [Xlin(9,:); Xnl(9,:)];
            x   = [Xlin(10,:); Xnl(10,:)];
            y   = [Xlin(11,:); Xnl(11,:)];
            z   = [Xlin(12,:); Xnl(12,:)];
            
            VT      = [Xlin(13,:); Xnl(13,:)]*kts;
            beta    = [Xlin(14,:); Xnl(14,:)]*deg;
            alpha   = [Xlin(15,:); Xnl(15,:)]*deg;
            
            
            delta_T = U(1,:);
            delta_e = U(2,:)*deg;
            delta_a = U(3,:)*deg;
            delta_r = U(4,:)*deg;
            
            % packaging to plot all states in a for-loop
            data_long   = [u; w; q; theta;   x; z];
            data_lat    = [v; p; r;   phi; psi; y];
            fig_long    = [app.UIAxes_1 app.UIAxes_2 app.UIAxes_3 app.UIAxes_4  app.UIAxes_5  app.UIAxes_6 ];
            fig_lat     = [app.UIAxes_7 app.UIAxes_8 app.UIAxes_9 app.UIAxes_10 app.UIAxes_11 app.UIAxes_12];
            
            % plots all of the states
            for k = 1:6
                plot(fig_long(k), T, data_long(2*k-1:2*k,:));
                grid(fig_long(k), "on")
                long_range = [min(data_long(2*k-1:2*k,:),[],'all') max(data_long(2*k-1:2*k,:),[],'all')];
                ylim(fig_long(k), [min(long_range(1), floor(data_long(2*k-1, 1)-0.01)) max(long_range(2), ceil(data_long(2*k-1, 1)+0.01))])
                
                plot(fig_lat(k) , T, data_lat(2*k-1:2*k,:));
                grid(fig_lat(k), "on")
                lat_range = [min(data_lat(2*k-1:2*k,:),[],'all') max(data_lat(2*k-1:2*k,:),[],'all')];
                ylim(fig_lat(k), [min(lat_range(1), floor(data_lat(2*k-1, 1)-0.01)) max(lat_range(2), ceil(data_lat(2*k-1, 1)+0.01))])
            end
            
            % Longitudinal
            % u
            app.UIAxes_1.YLabel.String = "u_b";
            % w
            app.UIAxes_2.YLabel.String = "w_b";
            % q
            app.UIAxes_3.YLabel.String = "Pitch-rate q [rad/sec]";
            % theta
            app.UIAxes_4.YLabel.String = "Pitch \theta [deg]";
            % x
            app.UIAxes_5.YLabel.String = "North x [m]";
            % z
            app.UIAxes_6.YLabel.String = "Altitude z [m]";
            app.UIAxes_6.XLabel.String = "Time";
            
            % Lateral
            % v
            app.UIAxes_7.YLabel.String = "v_b [m]";
            % p
            app.UIAxes_8.YLabel.String = "Roll p [rad/sec]";
            % r
            app.UIAxes_9.YLabel.String = "Yaw-rate r [rad/sec]";
            % phi
            app.UIAxes_10.YLabel.String = "Bank \phi [deg]";
            % psi
            app.UIAxes_11.YLabel.String = "Yaw \psi [deg]";
            % y
            app.UIAxes_12.YLabel.String = "East y [m]";
            app.UIAxes_12.XLabel.String = "Time";
            
            % Aerodynamic Speeds
            %VT
            plot(app.UIAxes_13, T, VT);
            grid(app.UIAxes_13, "on")
            ylim(app.UIAxes_13, [floor(min(VT, [], "all")-0.01), ceil(max(VT, [], "all")+0.01)])
            app.UIAxes_13.YLabel.String = "True Airspeed [kts]";
            % beta
            plot(app.UIAxes_14, T, beta);
            grid(app.UIAxes_14, "on")
            ylim(app.UIAxes_14, [floor(min(beta, [], "all")-0.01), ceil(max(beta, [], "all")+0.01)])
            app.UIAxes_14.YLabel.String = "Sideslip angle \beta [deg]";
            % alpha
            plot(app.UIAxes_15, T, alpha);
            grid(app.UIAxes_15, "on")
            ylim(app.UIAxes_15, [floor(min(alpha, [], "all")-0.01), ceil(max(alpha, [], "all")+0.01)])
            app.UIAxes_15.YLabel.String = "Angle of Attack \alpha [deg]";
            app.UIAxes_15.XLabel.String = "Time";
            
            % Controls
            % Thrust
            plot(app.UIAxes_16, T, delta_T);
            grid(app.UIAxes_16, "on")
            ylim(app.UIAxes_16, [floor(min(delta_T, [], "all")-0.01), ceil(max(delta_T, [], "all")+0.01)])
            app.UIAxes_16.YLabel.String = "Thrust \delta_T";
            % elevator
            plot(app.UIAxes_17, T, delta_e);
            grid(app.UIAxes_17, "on")
            ylim(app.UIAxes_17, [floor(min(delta_e, [], "all")-0.01), ceil(max(delta_e, [], "all")+0.01)])
            app.UIAxes_17.YLabel.String = "Elevator Deflection \delta_e [deg]";
            % aileron
            plot(app.UIAxes_18, T, delta_a);
            grid(app.UIAxes_18, "on")
            ylim(app.UIAxes_18, [floor(min(delta_a, [], "all")-0.01), ceil(max(delta_a, [], "all")+0.01)])
            app.UIAxes_18.YLabel.String = "Aileron Deflection \delta_a [deg]";
            % rudder
            plot(app.UIAxes_19, T, delta_r);
            grid(app.UIAxes_19, "on")
            ylim(app.UIAxes_19, [floor(min(delta_r, [], "all")-0.01), ceil(max(delta_r, [], "all")+0.01)])
            app.UIAxes_19.YLabel.String = "Rudder Deflection \delta_e [deg]";
            app.UIAxes_19.XLabel.String = "Time";
            
            
            geo = Xnl(end-2:end, :);
            if app.PlotTrajectoryRequiresthemappingtoolboxCheckBox.Value == 1
                fig = figure("Name", "Trajectory");
                geoaxes(fig, "Basemap","satellite");
                hold on
                geoplot(geo(1,:), geo(2,:), "LineWidth", 1.5);
                hold off
            end

        end
        
        function Results_ArgandDiagrams(app, AD)
            
            fig = [app.UIAxes_20 app.UIAxes_21 app.UIAxes_22 app.UIAxes_23 app.UIAxes_24;
                   app.UIAxes_25 app.UIAxes_26 app.UIAxes_27 app.UIAxes_28 app.UIAxes_29];

            colour2 = ['r', 'm', 'g', 'b', 'k'];
            colour1 = ['--r', '--m', '--g', '--b', '--k'];
            colour3 = ['or', 'om', 'og', 'ob', 'ok'];
            
            extrema = zeros(5, 1);
            
            for k = 1:2
                for coln = 1:5
                    
                    hold(fig(k,coln), "on")
                    grid(fig(k,coln), "on")
                    
                    xlabel(fig(k,coln), "Time")
                    ylabel(fig(k,coln), 'Real Axis')
                    zlabel(fig(k,coln), "Imaginary Axis")
                    
                    for row = 1:5
                        
                        plot3(fig(k,coln), AD(row,coln,k).lambda(:,3)   , AD(row,coln,k).lambda(:,1)   , AD(row,coln,k).lambda(:,2)   , colour1(3*row-2:3*row));
                        plot3(fig(k,coln), AD(row,coln,k).result(:,3)   , AD(row,coln,k).result(:,1)   , AD(row,coln,k).result(:,2)   , colour2(row));
                        plot3(fig(k,coln), AD(row,coln,k).lambdaEnd(:,3), AD(row,coln,k).lambdaEnd(:,1), AD(row,coln,k).lambdaEnd(:,2), colour3(2*row-1:2*row));
                        
                        extrema(row) = max(abs(AD(row,coln).lambdaEnd));
                        
                    end
                    
                    set(fig(k,coln), 'YDir','reverse')
                    view(fig(k,coln), -90, 0)
                    ylim(fig(k,coln), max(abs(extrema))*[-1 1])
                    zlim(fig(k,coln), max(abs(extrema))*[-1 1])
                    hold(fig(k,coln), "off")
                end
            end
                
        end
        
        function Results_MilitarySpecifications(app, MilSpec)
            
            % unpack
            dn_dalpha   = MilSpec.dn_dalpha;
            Wn          = MilSpec.natFreq;
            zeta        = MilSpec.dampRatio;
            Class       = MilSpec.Class;
            
            Wn_SP	= Wn(3:4);
            Wn_LP	= Wn(5:6);
            Wn_Alt  = Wn(10);
            
            Wn_Roll = Wn(7);
            Wn_DR   = Wn(8:9);
            Wn_SR   = Wn(11);
            
            zeta_SP	= zeta(3:4);
            zeta_LP	= zeta(5:6);
            zeta_Alt= zeta(10);
            
            zeta_Roll=zeta(7);
            zeta_DR = zeta(8:9);
            zeta_SR = zeta(11);

            if strcmp(app.AircraftClassDropDown.Value, "I")
                classVal = 1;
            elseif strcmp(app.AircraftClassDropDown.Value, "II")
                classVal = 2;
            elseif strcmp(app.AircraftClassDropDown.Value, "III")
                classVal = 3;
            elseif strcmp(app.AircraftClassDropDown.Value, "IV")
                classVal = 4;
            end
            
            if strcmp(app.FlightPhaseCategoryDropDown.Value, "A")
                catVal = 1;
            elseif strcmp(app.FlightPhaseCategoryDropDown.Value, "B")
                catVal = 2;
            elseif strcmp(app.FlightPhaseCategoryDropDown.Value, "C")
                catVal = 3;
            end
            
            for j = 1:3
                scaler(1, j)   = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).upr.k;
                exponent(1, j) = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).upr.n;
                ylimit(1, j)   = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).upr.y;
                xlimit(1, j)   = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).lft.x;
                
                scaler(2, j)   = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).lwr.k;
                exponent(2, j) = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).lwr.n;
                ylimit(2, j)   = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).lwr.y;
                
                wd_z(j).region = Class(classVal).Cat(catVal).SP.dampRatio.lvl(j).region;
                
                DR_zeta(j)      = Class(classVal).Cat(catVal).DR.lvl(j).zeta;
                DR_zetaWn(j)    = Class(classVal).Cat(catVal).DR.lvl(j).zetaWn;
                DR_Wn(j)      = Class(classVal).Cat(catVal).DR.lvl(j).Wn;
            end
            
            horz_points = logspace(min(0, floor(min(log(dn_dalpha)/log(10)))), ceil(max(log(dn_dalpha)/log(10))));
            
            for i = 1:2
                for j = 1:length(scaler(1,:))
                    boundary(i, j).dndalpha = horz_points(horz_points > xlimit(1, j));
                    
                    boundary(i, j).natFreq = scaler(i, j)*(boundary(i, j).dndalpha.^exponent(i, j));
                    
                    boundary(i, j).natFreq(boundary(i, j).natFreq > ylimit(1, j)) = ylimit(1, j);
                    boundary(i, j).natFreq(boundary(i, j).natFreq < ylimit(2, j)) = ylimit(2, j);
                end
            end
            
            
            %% SP wn/zeta
            semilogx(app.UIAxes_30, zeta_SP(1), Wn_SP(1), 'or','LineWidth', 1.5)
            hold(app.UIAxes_30, "on")
            grid(app.UIAxes_30, "on")
            title(app.UIAxes_30, 'MilSpec: Short Period')
            xlabel(app.UIAxes_30, 'Damping Ratio \zeta SP')
            ylabel(app.UIAxes_30, 'Nat. Frequency \omega_n SP')
            
            y_range = [min(min(Wn_SP), 0) max(max(Wn_SP), max(wd_z(3).region(:, 2)))];
            ylim(app.UIAxes_30, y_range)

            fill(app.UIAxes_30, wd_z(3).region(:,1), wd_z(3).region(:,2), "k", "FaceColor", "r"      , "FaceAlpha", 1, "EdgeAlpha", 0)
            fill(app.UIAxes_30, wd_z(2).region(:,1), wd_z(2).region(:,2), "k", "FaceColor", '#EDB120', "FaceAlpha", 1, "EdgeAlpha", 0)
            fill(app.UIAxes_30, wd_z(1).region(:,1), wd_z(1).region(:,2), "k", "FaceColor", "g"      , "FaceAlpha", 1, "EdgeAlpha", 0)

            semilogx(app.UIAxes_30, zeta_SP(2), Wn_SP(2), 'xm','LineWidth', 1.5)
            semilogx(app.UIAxes_30, wd_z(1).region(:, 1), wd_z(1).region(:, 2), "Color", "k", "LineWidth", 1.5)
            semilogx(app.UIAxes_30, wd_z(2).region(:, 1), wd_z(2).region(:, 2), "Color", "k", "LineWidth", 1.5)
            semilogx(app.UIAxes_30, wd_z(3).region(:, 1), wd_z(3).region(:, 2), "Color", "k", "LineWidth", 1.5)
            
            hold(app.UIAxes_30, "off")
            
            
            %% SP wn/ dndalpha      
            loglog(app.UIAxes_31, dn_dalpha, Wn_SP(1), 'or','LineWidth', 1.5)
            hold(app.UIAxes_31, "on")
            grid(app.UIAxes_31, "on")
            xlabel(app.UIAxes_31, ' n/\alpha [g/rad]')
            ylabel(app.UIAxes_31, ' Nat. Frequency \omega_n SP')
            title(app.UIAxes_31, 'MilSpec: Short Period')
            
            fill(app.UIAxes_31, [horz_points fliplr(boundary(1, 2).dndalpha) boundary(2, 2).dndalpha fliplr(boundary(2, 3).dndalpha)], [max(app.UIAxes_31.YLim).*ones(size(horz_points)) fliplr(boundary(1, 2).natFreq) boundary(2, 2).natFreq fliplr(boundary(2, 3).natFreq)],...
                "k", "FaceColor", "r"      , "FaceAlpha", 1, "EdgeAlpha", 0)
            fill(app.UIAxes_31, [boundary(1, 2).dndalpha fliplr(boundary(1, 1).dndalpha) boundary(2, 1).dndalpha fliplr(boundary(2, 2).dndalpha)], [boundary(1, 2).natFreq fliplr(boundary(1, 1).natFreq) boundary(2, 1).natFreq fliplr(boundary(2, 2).natFreq)],...
                "k", "FaceColor", '#EDB120', "FaceAlpha", 1, "EdgeAlpha", 0)
            fill(app.UIAxes_31, [boundary(1, 1).dndalpha fliplr(boundary(2, 1).dndalpha)], [boundary(1, 1).natFreq fliplr(boundary(2, 1).natFreq)],...
                "k", "FaceColor", "g"      , "FaceAlpha", 1, "EdgeAlpha", 0)
            
            loglog(app.UIAxes_31, dn_dalpha, Wn_SP(2), 'xm','LineWidth', 1.5)
            
            for i = 1:2
                for j = 1:length(scaler(1,:))
                    loglog(app.UIAxes_31, boundary(i, j).dndalpha, boundary(i, j).natFreq, 'k', 'LineWidth', 1.5);
                end
            end
            
            hold(app.UIAxes_31, "off")
            
            
            %% DUTCH ROLL
            for j = 1:3
                DR(j).x = (0.01:0.01:1);
                DR(j).y = DR_zetaWn(j)./DR(j).x;
                DR(j).x(DR(j).y>5) = NaN;
                DR(j).y(DR(j).y>5) = NaN;
                DR(j).x(DR(j).x<DR_zeta(j)) = DR_zeta(j);
                DR(j).y(DR(j).y<DR_Wn(j)) = DR_Wn(j);
            end
            
            hold(app.UIAxes_32, "on")
            xlabel(app.UIAxes_32, ' Damping Ratio \zeta DR')
            ylabel(app.UIAxes_32, ' Nat. Frequency \omega_n DR')
            title(app.UIAxes_32, 'MilSpec: Dutch Roll')
            
            for j = 1:3
                plot(app.UIAxes_32, DR(j).x, DR(j).y, 'k','LineWidth', 1.5)
            end
            
            plot(app.UIAxes_32, zeta_DR(1), Wn_DR(1), 'or','LineWidth', 1.5)
            plot(app.UIAxes_32, zeta_DR(2), Wn_DR(2), 'xm','LineWidth', 1.5)
            
            grid(app.UIAxes_32, "on")
            hold(app.UIAxes_32, "off")


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %% Add Paths
            addpath('FlightSim');
            addpath('savedFiles\savedAircraft');
            addpath('savedFiles\savedAircraft_ProfilePhotos');
            addpath('savedFiles\savedParameters');
            addpath('savedFiles\temp');
            
            
        end

        % Button pushed function: loadaircraftButton
        function loadaircraftButtonPushed(app, event)
            
            % if there is no file in the load box (ie the user just pressed
            % 'load' without picking a file first).
            if size(app.AircraftFileDropDown.Value)==0
                % Do nothing
                
            else
                filename = ['savedFiles\savedAircraft\' app.AircraftFileDropDown.Value];
                load(filename);
                
                % Change the value of the 'file name' EditField to match 
                % the file name.
                app.AircraftNameEditField.Value = app.AircraftFileDropDown.Value(1:end-4);
                
                aircraftEditFields(app, aircraft);
                
                
            end
        end

        % Button pushed function: saveaircraftButton
        function saveaircraftButtonPushed(app, event)
         
            aircraft = aircraftStructure(app);
            
            filename = ['savedFiles\savedAircraft\' app.AircraftNameEditField.Value];
            save(filename, 'aircraft');
            
        end

        % Drop down opening function: AircraftFileDropDown
        function AircraftFileDropDownOpening(app, event)
            
            % Log all the file names in this folder into 'allNames'
            % variable.
            allFiles = dir( 'savedFiles\savedAircraft' );
            allNames = { allFiles.name };

            % The drop down menu will show the names of al the files. The
            % first 2 files show as wierd dots so disregard them.
            app.AircraftFileDropDown.Items = allNames(3:end);  
            
        end

        % Drop down opening function: FileDropDown
        function FileDropDownOpening(app, event)
            
            % Log all the file names in this folder into 'allNames'
            % variable.
            allFiles = dir('savedFiles\savedParameters');
            allNames = { allFiles.name };

            % The drop down menu will show the names of al the files. The
            % first 2 files show as wierd dots so disregard them.
            app.FileDropDown.Items = allNames(3:end);
            
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            
            % if there is no file in the load box (ie the user just pressed
            % 'load' without picking a file first).
            if size(app.FileDropDown.Value)==0
                % Do nothing
                
            else
                filename = ['savedFiles\savedParamters' app.FileDropDown.Value];
                load(filename);
                
                % Change the value of the 'file name' EditField to match 
                % the file name.
                app.FileNameEditField.Value = app.FileDropDown.Value(1:end-4);
                
                
                parameters.aircraft = aircraft;
                parameters.operation = operation;
                parameters.environment = environment;
                parameters.simulation = simulation;
                
                parametersEditFields(app, parameters);
            end
            
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
         
            parameters = parametersStructure(app);
            
            % Unpack
            aircraft    = parameters.aircraft;
            operation   = parameters.operation;
            environment = parameters.environment;
            simulation  = parameters.simulation;
            
            filename = ['savedFiles\savedParameters' app.FileNameEditField.Value];
            save(filename, 'aircraft', 'operation', 'environment', 'simulation');
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            app.ManualControlsButton.Value
            
            app.Label.Text = "Simulating...";
            % Need to pause the sim to give the label time to refresh and
            % show the above string.
            pause(0.01);
            
            if app.ManualControlsButton.Value == true
                
                % TODO: let user upload a .csv of controls transient.
                
            elseif app.BuiltinOperationsButton.Value == true
                
                parameters = parametersStructure(app);
                
                % Unpack
                aircraft    = parameters.aircraft;
                operation   = parameters.operation;
                environment = parameters.environment;
                simulation  = parameters.simulation;
            
                
                filename = 'savedFiles\temp\appParameters';
                save(filename, 'aircraft', 'operation', 'environment', 'simulation');
                
                %% SIMULATION RUNS %%
                
                app.Label.Text = "Complete!";
                
                output = mainFunction();
                
                Results_TransientResponse(app, output.Transient);
              
                % linear Tables
                app.UITableA.Data = output.LinearAnalysis.A;
                app.UITableB.Data = output.LinearAnalysis.B;
                app.UITableV.Data = output.LinearAnalysis.eigVectors;
                app.UITableE.Data = output.LinearAnalysis.eigValues;
                app.UITableWnZ.Data = [output.LinearAnalysis.natFreq output.LinearAnalysis.dampFactor];
                
                Results_ArgandDiagrams(app, output.LinearAnalysis.Stability)
                
                Results_MilitarySpecifications(app, output.MilSpec)
                
                
            else
                % If the button is broken
                disp("button group didn't work")
            end      
            
        end

        % Button pushed function: ProfileImageFolderButton
        function ProfileImageFolderButtonPushed(app, event)
            
            % Open Folder 
            winopen('savedFiles\savedAircraft_ProfilePhotos');
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
            % Open Folder & chose file
            filename = uigetfile('savedFiles\savedAircraft_ProfilePhotos');
            app.Image.ImageSource = filename;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AircraftTab = uitab(app.TabGroup);
            app.AircraftTab.Title = 'Aircraft';
            app.TabGroup2 = uitabgroup(app.AircraftTab);
            app.InertiaTab = uitab(app.TabGroup2);
            app.InertiaTab.Title = ' Inertia';
            app.MassEditFieldLabel = uilabel(app.InertiaTab);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.InertiaTab, 'numeric');
            app.MassEditField.Value = 2087;
            app.IxxEditFieldLabel = uilabel(app.InertiaTab);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.IxxEditField = uieditfield(app.InertiaTab, 'numeric');
            app.IxxEditField.Value = 5066;
            app.IyyEditFieldLabel = uilabel(app.InertiaTab);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.IyyEditField = uieditfield(app.InertiaTab, 'numeric');
            app.IyyEditField.Value = 6578;
            app.IzzEditFieldLabel = uilabel(app.InertiaTab);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.IzzEditField = uieditfield(app.InertiaTab, 'numeric');
            app.IzzEditField.Value = 10975;
            app.IxzEditFieldLabel = uilabel(app.InertiaTab);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.IxzEditField = uieditfield(app.InertiaTab, 'numeric');
            app.IxzEditField.Value = 203;
            app.IxyEditFieldLabel = uilabel(app.InertiaTab);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.IxyEditField = uieditfield(app.InertiaTab, 'numeric');
            app.IyzEditFieldLabel = uilabel(app.InertiaTab);
            app.IyzEditFieldLabel.Text = 'Iyz';
            app.IyzEditField = uieditfield(app.InertiaTab, 'numeric');
            app.AircraftMomentofInertiaskgm2Label = uilabel(app.InertiaTab);
            app.AircraftMomentofInertiaskgm2Label.Text = 'Aircraft Moment of Inertias (kg m^2)';
            app.kgLabel = uilabel(app.InertiaTab);
            app.kgLabel.Text = 'kg';
            app.GeometryTab = uitab(app.TabGroup2);
            app.GeometryTab.Title = 'Geometry';
            app.PlanformAreaEditFieldLabel = uilabel(app.GeometryTab);
            app.PlanformAreaEditFieldLabel.Text = 'Planform Area';
            app.PlanformAreaEditField = uieditfield(app.GeometryTab, 'numeric');
            app.PlanformAreaEditField.Value = 16.29;
            app.WingSpanEditFieldLabel = uilabel(app.GeometryTab);
            app.WingSpanEditFieldLabel.Text = 'Wing Span';
            app.WingSpanEditField = uieditfield(app.GeometryTab, 'numeric');
            app.WingSpanEditField.Value = 10.12;
            app.ChordLengthEditFieldLabel = uilabel(app.GeometryTab);
            app.ChordLengthEditFieldLabel.Text = 'Chord Length';
            app.ChordLengthEditField = uieditfield(app.GeometryTab, 'numeric');
            app.ChordLengthEditField.Value = 1.652;
            app.m2Label = uilabel(app.GeometryTab);
            app.m2Label.Text = 'm^2';
            app.mLabel = uilabel(app.GeometryTab);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.GeometryTab);
            app.mLabel_2.Text = 'm';
            app.PropulsionTab = uitab(app.TabGroup2);
            app.PropulsionTab.Title = '   Propulsion';
            app.MaximumPowerEditFieldLabel = uilabel(app.PropulsionTab);
            app.MaximumPowerEditFieldLabel.Text = 'Maximum Power';
            app.MaximumPowerEditField = uieditfield(app.PropulsionTab, 'numeric');
            app.MaximumPowerEditField.Value = 708000;
            app.EfficiencyEditFieldLabel = uilabel(app.PropulsionTab);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.EfficiencyEditField = uieditfield(app.PropulsionTab, 'numeric');
            app.EfficiencyEditField.Value = 0.8;
            app.WLabel = uilabel(app.PropulsionTab);
            app.WLabel.Text = 'W';
            app.ControlSurfacesTab = uitab(app.TabGroup2);
            app.ControlSurfacesTab.Title = 'Control Surfaces';
            app.ElevatorUpperLimitEditFieldLabel = uilabel(app.ControlSurfacesTab);
            app.ElevatorUpperLimitEditFieldLabel.Text = 'Elevator Upper Limit';
            app.ElevatorUpperLimitEditField = uieditfield(app.ControlSurfacesTab, 'numeric');
            app.ElevatorUpperLimitEditField.Value = 0.436;
            app.ElevatorLowerLimitEditFieldLabel = uilabel(app.ControlSurfacesTab);
            app.ElevatorLowerLimitEditFieldLabel.Text = 'Elevator Lower Limit';
            app.ElevatorLowerLimitEditField = uieditfield(app.ControlSurfacesTab, 'numeric');
            app.ElevatorLowerLimitEditField.Value = -0.436;
            app.AileronUpperLimitEditFieldLabel = uilabel(app.ControlSurfacesTab);
            app.AileronUpperLimitEditFieldLabel.Text = 'Aileron Upper Limit';
            app.AileronUpperLimitEditField = uieditfield(app.ControlSurfacesTab, 'numeric');
            app.AileronUpperLimitEditField.Value = 0.436;
            app.AileronLowerLimitEditFieldLabel = uilabel(app.ControlSurfacesTab);
            app.AileronLowerLimitEditFieldLabel.Text = 'Aileron Lower Limit';
            app.AileronLowerLimitEditField = uieditfield(app.ControlSurfacesTab, 'numeric');
            app.AileronLowerLimitEditField.Value = -0.436;
            app.RudderLowerLimitEditFieldLabel = uilabel(app.ControlSurfacesTab);
            app.RudderLowerLimitEditFieldLabel.Text = 'Rudder Lower Limit';
            app.RudderLowerLimitEditField = uieditfield(app.ControlSurfacesTab, 'numeric');
            app.RudderLowerLimitEditField.Value = -0.436;
            app.RudderUpperLimitEditFieldLabel = uilabel(app.ControlSurfacesTab);
            app.RudderUpperLimitEditFieldLabel.Text = 'Rudder Upper Limit';
            app.RudderUpperLimitEditField = uieditfield(app.ControlSurfacesTab, 'numeric');
            app.RudderUpperLimitEditField.Value = 0.436;
            app.AerodynamicsTab = uitab(app.TabGroup2);
            app.AerodynamicsTab.Title = 'Aerodynamics';
            app.TabGroup3 = uitabgroup(app.AerodynamicsTab);
            app.LongitudinalTab = uitab(app.TabGroup3);
            app.LongitudinalTab.Title = 'Longitudinal';
            app.LiftLabel = uilabel(app.LongitudinalTab);
            app.LiftLabel.Text = 'Lift';
            app.CL_0EditFieldLabel = uilabel(app.LongitudinalTab);
            app.CL_0EditFieldLabel.Text = 'CL_0';
            app.CL_0EditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CL_0EditField.Value = 0.2439;
            app.CL_alphaEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CL_alphaEditFieldLabel.Text = 'CL_alpha';
            app.CL_alphaEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CL_alphaEditField.Value = 4.659;
            app.CL_qEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CL_qEditFieldLabel.Text = 'CL_ q';
            app.CL_qEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CL_qEditField.Value = 7.96;
            app.DragLabel = uilabel(app.LongitudinalTab);
            app.DragLabel.Text = 'Drag';
            app.CD_0EditFieldLabel = uilabel(app.LongitudinalTab);
            app.CD_0EditFieldLabel.Text = 'CD_0';
            app.CD_0EditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CD_0EditField.Value = 0.0215;
            app.kEditFieldLabel = uilabel(app.LongitudinalTab);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.kEditField.Value = 0.0555;
            app.PitchMomentLabel = uilabel(app.LongitudinalTab);
            app.PitchMomentLabel.Text = 'Pitch Moment';
            app.Cm_0EditFieldLabel = uilabel(app.LongitudinalTab);
            app.Cm_0EditFieldLabel.Text = 'Cm_0';
            app.Cm_0EditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.Cm_0EditField.Value = 0.06;
            app.Cm_qEditFieldLabel = uilabel(app.LongitudinalTab);
            app.Cm_qEditFieldLabel.Text = 'Cm_q';
            app.Cm_qEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.Cm_qEditField.Value = -17.72;
            app.Cm_alphaEditFieldLabel = uilabel(app.LongitudinalTab);
            app.Cm_alphaEditFieldLabel.Text = 'Cm_alpha';
            app.Cm_alphaEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.Cm_alphaEditField.Value = -0.802;
            app.CL_alphadotEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CL_alphadotEditFieldLabel.Text = 'CL_alphadot';
            app.CL_alphadotEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CL_alphadotEditField.Value = -1.987;
            app.CL_delevatorEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CL_delevatorEditFieldLabel.Text = 'CL_d(elevator)';
            app.CL_delevatorEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CL_delevatorEditField.Value = 0.532;
            app.Cm_alphadotEditFieldLabel = uilabel(app.LongitudinalTab);
            app.Cm_alphadotEditFieldLabel.Text = 'Cm_alphadot';
            app.Cm_alphadotEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.Cm_alphadotEditField.Value = -2.21;
            app.Cm_delevatorEditFieldLabel = uilabel(app.LongitudinalTab);
            app.Cm_delevatorEditFieldLabel.Text = 'Cm_d(elevator)';
            app.Cm_delevatorEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.Cm_delevatorEditField.Value = -1.822;
            app.LateralTab = uitab(app.TabGroup3);
            app.LateralTab.Title = 'Lateral';
            app.SideForceLabel = uilabel(app.LateralTab);
            app.SideForceLabel.Text = 'Side Force';
            app.RollMomentLabel = uilabel(app.LateralTab);
            app.RollMomentLabel.Text = 'Roll Moment';
            app.YawMomentLabel = uilabel(app.LateralTab);
            app.YawMomentLabel.Text = 'Yaw Moment';
            app.CY_0EditFieldLabel = uilabel(app.LateralTab);
            app.CY_0EditFieldLabel.Text = 'CY_0';
            app.CY_0EditField = uieditfield(app.LateralTab, 'numeric');
            app.CY_betaEditFieldLabel = uilabel(app.LateralTab);
            app.CY_betaEditFieldLabel.Text = 'CY_beta';
            app.CY_betaEditField = uieditfield(app.LateralTab, 'numeric');
            app.CY_betaEditField.Value = -0.507;
            app.CY_pEditFieldLabel = uilabel(app.LateralTab);
            app.CY_pEditFieldLabel.Text = 'CY_ p';
            app.CY_pEditField = uieditfield(app.LateralTab, 'numeric');
            app.CY_pEditField.Value = -0.128;
            app.Cl_0EditFieldLabel = uilabel(app.LateralTab);
            app.Cl_0EditFieldLabel.Text = 'Cl_0';
            app.Cl_0EditField = uieditfield(app.LateralTab, 'numeric');
            app.Cl_betaEditFieldLabel = uilabel(app.LateralTab);
            app.Cl_betaEditFieldLabel.Text = 'Cl_beta';
            app.Cl_betaEditField = uieditfield(app.LateralTab, 'numeric');
            app.Cl_betaEditField.Value = -0.0852;
            app.Cl_betadotEditFieldLabel = uilabel(app.LateralTab);
            app.Cl_betadotEditFieldLabel.Text = 'Cl_betadot';
            app.Cl_betadotEditField = uieditfield(app.LateralTab, 'numeric');
            app.Cl_betadotEditField.Value = -0.0004;
            app.Cn_0EditFieldLabel = uilabel(app.LateralTab);
            app.Cn_0EditFieldLabel.Text = 'Cn_0';
            app.Cn_0EditField = uieditfield(app.LateralTab, 'numeric');
            app.Cn_betadotEditFieldLabel = uilabel(app.LateralTab);
            app.Cn_betadotEditFieldLabel.Text = 'Cn_betadot';
            app.Cn_betadotEditField = uieditfield(app.LateralTab, 'numeric');
            app.Cn_betadotEditField.Value = 0.0015;
            app.Cn_betaEditFieldLabel = uilabel(app.LateralTab);
            app.Cn_betaEditFieldLabel.Text = 'Cn_beta';
            app.Cn_betaEditField = uieditfield(app.LateralTab, 'numeric');
            app.Cn_betaEditField.Value = 0.107;
            app.CY_rEditFieldLabel = uilabel(app.LateralTab);
            app.CY_rEditFieldLabel.Text = 'CY_r';
            app.CY_rEditField = uieditfield(app.LateralTab, 'numeric');
            app.CY_rEditField.Value = 0.336;
            app.CY_daileronEditFieldLabel = uilabel(app.LateralTab);
            app.CY_daileronEditFieldLabel.Text = 'CY_d(aileron)';
            app.CY_daileronEditField = uieditfield(app.LateralTab, 'numeric');
            app.CY_drudderEditFieldLabel = uilabel(app.LateralTab);
            app.CY_drudderEditFieldLabel.Text = 'CY_d(rudder)';
            app.CY_drudderEditField = uieditfield(app.LateralTab, 'numeric');
            app.CY_drudderEditField.Value = 0.05;
            app.CY_betadotEditFieldLabel = uilabel(app.LateralTab);
            app.CY_betadotEditFieldLabel.Text = 'CY_betadot';
            app.CY_betadotEditField = uieditfield(app.LateralTab, 'numeric');
            app.CY_betadotEditField.Value = -0.0032;
            app.Cl_pEditFieldLabel = uilabel(app.LateralTab);
            app.Cl_pEditFieldLabel.Text = 'Cl_p';
            app.Cl_pEditField = uieditfield(app.LateralTab, 'numeric');
            app.Cl_pEditField.Value = -0.328;
            app.Cl_rEditFieldLabel = uilabel(app.LateralTab);
            app.Cl_rEditFieldLabel.Text = 'Cl_r';
            app.Cl_rEditField = uieditfield(app.LateralTab, 'numeric');
            app.Cl_rEditField.Value = 0.0776;
            app.Cl_daileronEditFieldLabel = uilabel(app.LateralTab);
            app.Cl_daileronEditFieldLabel.Text = 'Cl_d(aileron)';
            app.Cl_daileronEditField = uieditfield(app.LateralTab, 'numeric');
            app.Cl_daileronEditField.Value = -0.164;
            app.Cl_drudderEditFieldLabel = uilabel(app.LateralTab);
            app.Cl_drudderEditFieldLabel.Text = 'Cl_d(rudder)';
            app.Cl_drudderEditField = uieditfield(app.LateralTab, 'numeric');
            app.Cl_drudderEditField.Value = 0.0302;
            app.Cn_pEditFieldLabel = uilabel(app.LateralTab);
            app.Cn_pEditFieldLabel.Text = 'Cn_p';
            app.Cn_pEditField = uieditfield(app.LateralTab, 'numeric');
            app.Cn_pEditField.Value = -0.0226;
            app.Cn_rEditFieldLabel = uilabel(app.LateralTab);
            app.Cn_rEditFieldLabel.Text = 'Cn_r';
            app.Cn_rEditField = uieditfield(app.LateralTab, 'numeric');
            app.Cn_rEditField.Value = -0.16;
            app.Cn_daileronEditFieldLabel = uilabel(app.LateralTab);
            app.Cn_daileronEditFieldLabel.Text = 'Cn_d(aileron)';
            app.Cn_daileronEditField = uieditfield(app.LateralTab, 'numeric');
            app.Cn_daileronEditField.Value = 0.0048;
            app.Cn_drudderEditFieldLabel = uilabel(app.LateralTab);
            app.Cn_drudderEditFieldLabel.Text = 'Cn_d(rudder)';
            app.Cn_drudderEditField = uieditfield(app.LateralTab, 'numeric');
            app.Cn_drudderEditField.Value = -0.115;
            app.OtherTab = uitab(app.TabGroup3);
            app.OtherTab.Title = 'Other';
            app.CentreofGravityDuringMeasurementsEditFieldLabel = uilabel(app.OtherTab);
            app.CentreofGravityDuringMeasurementsEditFieldLabel.Text = 'Centre of Gravity During Measurements';
            app.CentreofGravityDuringMeasurementsEditField = uieditfield(app.OtherTab, 'numeric');
            app.CentreofGravityDuringMeasurementsEditField.Value = 0.265;
            app.perunitMACLabel = uilabel(app.OtherTab);
            app.perunitMACLabel.Text = 'per unit MAC';
            app.OptionsTab = uitab(app.TabGroup2);
            app.OptionsTab.Title = 'Options';
            app.AircraftFileDropDownLabel = uilabel(app.OptionsTab);
            app.AircraftFileDropDownLabel.Text = 'Aircraft File';
            app.AircraftFileDropDown = uidropdown(app.OptionsTab);
            app.AircraftFileDropDown.Items = {};
            app.AircraftFileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AircraftFileDropDownOpening, true);
            app.AircraftFileDropDown.Value = {};
            app.loadaircraftButton = uibutton(app.OptionsTab, 'push');
            app.loadaircraftButton.ButtonPushedFcn = createCallbackFcn(app, @loadaircraftButtonPushed, true);
            app.loadaircraftButton.Text = 'load aircraft';
            app.saveaircraftButton = uibutton(app.OptionsTab, 'push');
            app.saveaircraftButton.ButtonPushedFcn = createCallbackFcn(app, @saveaircraftButtonPushed, true);
            app.saveaircraftButton.Text = 'save aircraft';
            app.AircraftNameEditFieldLabel = uilabel(app.OptionsTab);
            app.AircraftNameEditFieldLabel.Text = 'Aircraft Name';
            app.AircraftNameEditField = uieditfield(app.OptionsTab, 'text');
            app.AircraftDescriptionTextAreaLabel = uilabel(app.OptionsTab);
            app.AircraftDescriptionTextAreaLabel.Text = 'Aircraft Description';
            app.AircraftDescriptionTextArea = uitextarea(app.OptionsTab);
            app.Image = uiimage(app.OptionsTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.ProfileImageFolderButton = uibutton(app.OptionsTab, 'push');
            app.ProfileImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ProfileImageFolderButtonPushed, true);
            app.ProfileImageFolderButton.Text = 'Profile Image Folder';
            app.AircraftClassDropDownLabel = uilabel(app.OptionsTab);
            app.AircraftClassDropDownLabel.Text = 'Aircraft Class';
            app.AircraftClassDropDown = uidropdown(app.OptionsTab);
            app.AircraftClassDropDown.Items = {'I', 'II', 'III', 'IV'};
            app.AircraftClassDropDown.Value = 'I';
            app.EnvironmentTab = uitab(app.TabGroup);
            app.EnvironmentTab.Title = 'Environment';
            app.gravityEditFieldLabel = uilabel(app.EnvironmentTab);
            app.gravityEditFieldLabel.Text = 'gravity';
            app.gravityEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.gravityEditField.Value = 9.81;
            app.WindSpeedLabel = uilabel(app.EnvironmentTab);
            app.WindSpeedLabel.Text = 'Wind Speed';
            app.WindSpeedEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.SeaLevelDensityEditFieldLabel = uilabel(app.EnvironmentTab);
            app.SeaLevelDensityEditFieldLabel.Text = 'Sea Level Density';
            app.SeaLevelDensityEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.SeaLevelDensityEditField.Value = 1.225;
            app.SeaLevelTemperatureEditFieldLabel = uilabel(app.EnvironmentTab);
            app.SeaLevelTemperatureEditFieldLabel.Text = 'Sea Level Temperature';
            app.SeaLevelTemperatureEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.SeaLevelTemperatureEditField.Value = 288.16;
            app.LapseRateEditFieldLabel = uilabel(app.EnvironmentTab);
            app.LapseRateEditFieldLabel.Text = 'Lapse Rate';
            app.LapseRateEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.LapseRateEditField.Value = 0.0065;
            app.UniversalGasConstantEditFieldLabel = uilabel(app.EnvironmentTab);
            app.UniversalGasConstantEditFieldLabel.Text = 'Universal Gas Constant';
            app.UniversalGasConstantEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.UniversalGasConstantEditField.Value = 287;
            app.WindBearingEditFieldLabel = uilabel(app.EnvironmentTab);
            app.WindBearingEditFieldLabel.Text = 'Wind Bearing';
            app.WindBearingEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.SealLevelPressureEditFieldLabel = uilabel(app.EnvironmentTab);
            app.SealLevelPressureEditFieldLabel.Text = 'Seal Level Pressure';
            app.SealLevelPressureEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.OperationTab = uitab(app.TabGroup);
            app.OperationTab.Title = 'Operation';
            app.TrimmedAltitudeEditFieldLabel = uilabel(app.OperationTab);
            app.TrimmedAltitudeEditFieldLabel.Text = 'Trimmed Altitude';
            app.TrimmedAltitudeEditField = uieditfield(app.OperationTab, 'numeric');
            app.TrimmedAltitudeEditField.Value = -152.4;
            app.CentreofGravityPositionEditFieldLabel = uilabel(app.OperationTab);
            app.CentreofGravityPositionEditFieldLabel.Text = 'Centre of Gravity Position';
            app.CentreofGravityPositionEditField = uieditfield(app.OperationTab, 'numeric');
            app.CentreofGravityPositionEditField.Value = 0.265;
            app.ButtonGroup = uibuttongroup(app.OperationTab);
            app.ButtonGroup.Title = 'Button Group';
            app.BuiltinOperationsButton = uiradiobutton(app.ButtonGroup);
            app.BuiltinOperationsButton.Text = 'Built-in Operations';
            app.BuiltinOperationsButton.Value = true;
            app.ManualControlsButton = uiradiobutton(app.ButtonGroup);
            app.ManualControlsButton.Text = 'Manual Controls';
            app.TabGroup4 = uitabgroup(app.OperationTab);
            app.BuiltinOperationsTab = uitab(app.TabGroup4);
            app.BuiltinOperationsTab.Title = 'Built-in Operations';
            app.OperationNameDropDownLabel = uilabel(app.BuiltinOperationsTab);
            app.OperationNameDropDownLabel.Text = 'Operation Name';
            app.OperationNameDropDown = uidropdown(app.BuiltinOperationsTab);
            app.OperationNameDropDown.Items = {'Trim', 'Elevator Impulse', 'Aileron Impulse', 'Rudder Impulse'};
            app.OperationNameDropDown.Value = 'Trim';
            app.ManualControlsTab = uitab(app.TabGroup4);
            app.ManualControlsTab.Title = 'Manual Controls';
            app.ImportControlsButton = uibutton(app.ManualControlsTab, 'push');
            app.ImportControlsButton.Text = 'Import Controls';
            app.CalibrateButtonGroup = uibuttongroup(app.ManualControlsTab);
            app.CalibrateButtonGroup.Title = 'Calibrate';
            app.RelativetoTrimButton = uiradiobutton(app.CalibrateButtonGroup);
            app.RelativetoTrimButton.Text = 'Relative to Trim';
            app.RelativetoTrimButton.Value = true;
            app.TrimmedBearingEditFieldLabel = uilabel(app.OperationTab);
            app.TrimmedBearingEditFieldLabel.Text = 'Trimmed Bearing';
            app.TrimmedBearingEditField = uieditfield(app.OperationTab, 'numeric');
            app.TrimmedAirspeedEditFieldLabel = uilabel(app.OperationTab);
            app.TrimmedAirspeedEditFieldLabel.Text = 'Trimmed Airspeed';
            app.TrimmedAirspeedEditField = uieditfield(app.OperationTab, 'numeric');
            app.TrimmedAirspeedEditField.Value = 100;
            app.TrimmedSideslipAngleEditFieldLabel = uilabel(app.OperationTab);
            app.TrimmedSideslipAngleEditFieldLabel.Text = 'Trimmed Sideslip Angle';
            app.TrimmedSideslipAngleEditField = uieditfield(app.OperationTab, 'numeric');
            app.FlightPhaseCategoryDropDownLabel = uilabel(app.OperationTab);
            app.FlightPhaseCategoryDropDownLabel.Text = 'Flight Phase Category';
            app.FlightPhaseCategoryDropDown = uidropdown(app.OperationTab);
            app.FlightPhaseCategoryDropDown.Items = {'A', 'B', 'C'};
            app.FlightPhaseCategoryDropDown.Value = 'A';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = '       Simulation   ';
            app.RUNButton = uibutton(app.SimulationTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.TimeSpanLabel = uilabel(app.SimulationTab);
            app.TimeSpanLabel.Text = 'Time Span';
            app.TimeSpanEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TimeSpanEditField.Value = 30;
            app.TimeStepEditFieldLabel = uilabel(app.SimulationTab);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TimeStepEditField.Value = 0.01;
            app.Label = uilabel(app.SimulationTab);
            app.Label.Text = '';
            app.PlotTrajectoryRequiresthemappingtoolboxCheckBox = uicheckbox(app.SimulationTab);
            app.PlotTrajectoryRequiresthemappingtoolboxCheckBox.Text = 'Plot Trajectory? (Requires the mapping toolbox)';
            app.AnimateRequirestheaerospacetoolboxCheckBox = uicheckbox(app.SimulationTab);
            app.AnimateRequirestheaerospacetoolboxCheckBox.Text = 'Animate? (Requires the aerospace toolbox)';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = '       Results    ';
            app.TabGroup5 = uitabgroup(app.ResultsTab);
            app.TransientResponseTab = uitab(app.TabGroup5);
            app.TransientResponseTab.Title = 'Transient Response';
            app.TabGroup6 = uitabgroup(app.TransientResponseTab);
            app.LongitudinalTab_2 = uitab(app.TabGroup6);
            app.LongitudinalTab_2.Title = 'Longitudinal';
            app.UIAxes_1 = uiaxes(app.LongitudinalTab_2);
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.LongitudinalTab_2);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.LongitudinalTab_2);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.LongitudinalTab_2);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.LongitudinalTab_2);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.LongitudinalTab_2);
            zlabel(app.UIAxes_6, 'Z')
            app.LateralTab_2 = uitab(app.TabGroup6);
            app.LateralTab_2.Title = 'Lateral';
            app.UIAxes_7 = uiaxes(app.LateralTab_2);
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.LateralTab_2);
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.LateralTab_2);
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_10 = uiaxes(app.LateralTab_2);
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_11 = uiaxes(app.LateralTab_2);
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_12 = uiaxes(app.LateralTab_2);
            zlabel(app.UIAxes_12, 'Z')
            app.AerodynamicFrameTab = uitab(app.TabGroup6);
            app.AerodynamicFrameTab.Title = 'Aerodynamic Frame';
            app.UIAxes_13 = uiaxes(app.AerodynamicFrameTab);
            zlabel(app.UIAxes_13, 'Z')
            app.UIAxes_14 = uiaxes(app.AerodynamicFrameTab);
            zlabel(app.UIAxes_14, 'Z')
            app.UIAxes_15 = uiaxes(app.AerodynamicFrameTab);
            zlabel(app.UIAxes_15, 'Z')
            app.ControlsTab = uitab(app.TabGroup6);
            app.ControlsTab.Title = 'Controls';
            app.UIAxes_16 = uiaxes(app.ControlsTab);
            zlabel(app.UIAxes_16, 'Z')
            app.UIAxes_17 = uiaxes(app.ControlsTab);
            zlabel(app.UIAxes_17, 'Z')
            app.UIAxes_18 = uiaxes(app.ControlsTab);
            xlabel(app.UIAxes_18, 'X')
            ylabel(app.UIAxes_18, 'Y')
            zlabel(app.UIAxes_18, 'Z')
            app.UIAxes_19 = uiaxes(app.ControlsTab);
            zlabel(app.UIAxes_19, 'Z')
            app.LinearAnalysisTab = uitab(app.TabGroup5);
            app.LinearAnalysisTab.Title = 'Linear Analysis';
            app.TabGroup7 = uitabgroup(app.LinearAnalysisTab);
            app.DynamicMatriciesTab = uitab(app.TabGroup7);
            app.DynamicMatriciesTab.Title = 'Dynamic Matricies';
            app.UITableA = uitable(app.DynamicMatriciesTab);
            app.UITableA.ColumnName = {'u'; 'v'; 'w'; 'p'; 'q'; 'r'; 'phi'; 'theta'; 'psi'; 'x'; 'y'; 'z'};
            app.UITableA.RowName = {'du'; 'dv'; 'dw'; 'dp'; 'dq'; 'dr'; 'dphi'; 'dtheta'; 'dpsi'; 'dx'; 'dy'; 'dz'};
            app.UITableB = uitable(app.DynamicMatriciesTab);
            app.UITableB.ColumnName = {'\delta_T'; '\delta_e'; '\delta_a'; '\delta_r'};
            app.UITableB.RowName = {'du'; 'dv'; 'dw'; 'dp'; 'dq'; 'dr'; 'dphi'; 'dtheta'; 'dpsi'; 'dx'; 'dy'; 'dz'};
            app.EigenAnalysisTab = uitab(app.TabGroup7);
            app.EigenAnalysisTab.Title = 'Eigen Analysis';
            app.UITableV = uitable(app.EigenAnalysisTab);
            app.UITableV.ColumnName = {'u'; 'v'; 'w'; 'p'; 'q'; 'r'; 'phi'; 'theta'; 'psi'; 'x'; 'y'; 'z'};
            app.UITableV.RowName = {'du'; 'dv'; 'dw'; 'dp'; 'dq'; 'dr'; 'dphi'; 'dtheta'; 'dpsi'; 'dx'; 'dy'; 'dz'};
            app.UITableE = uitable(app.EigenAnalysisTab);
            app.UITableE.ColumnName = {'Eigen Values'};
            app.UITableE.RowName = {'o'; 'o'; 'SP'; 'SP'; 'LP'; 'LP'; 'Roll'; 'DR'; 'DR'; 'Alt'; 'SR'; 'o'};
            app.UITableWnZ = uitable(app.EigenAnalysisTab);
            app.UITableWnZ.ColumnName = {'Natural Frequency'; 'Damping Factor'};
            app.UITableWnZ.RowName = {'o'; 'o'; 'SP'; 'SP'; 'LP'; 'LP'; 'Roll'; 'DR'; 'DR'; 'Alt'; 'SR'; 'o'};
            app.ArgandDiagramsTab_2 = uitab(app.TabGroup7);
            app.ArgandDiagramsTab_2.Title = 'Argand Diagrams';
            app.TabGroup8 = uitabgroup(app.ArgandDiagramsTab_2);
            app.Tab = uitab(app.TabGroup8);
            app.Tab.Title = '1';
            app.UIAxes_20 = uiaxes(app.Tab);
            zlabel(app.UIAxes_20, 'Z')
            app.Tab_2 = uitab(app.TabGroup8);
            app.Tab_2.Title = '2';
            app.UIAxes_21 = uiaxes(app.Tab_2);
            zlabel(app.UIAxes_21, 'Z')
            app.Tab_3 = uitab(app.TabGroup8);
            app.Tab_3.Title = '3';
            app.UIAxes_22 = uiaxes(app.Tab_3);
            zlabel(app.UIAxes_22, 'Z')
            app.Tab_4 = uitab(app.TabGroup8);
            app.Tab_4.Title = '4';
            app.UIAxes_23 = uiaxes(app.Tab_4);
            zlabel(app.UIAxes_23, 'Z')
            app.Tab_5 = uitab(app.TabGroup8);
            app.Tab_5.Title = '5';
            app.UIAxes_24 = uiaxes(app.Tab_5);
            zlabel(app.UIAxes_24, 'Z')
            app.Tab_6 = uitab(app.TabGroup8);
            app.Tab_6.Title = '6';
            app.UIAxes_25 = uiaxes(app.Tab_6);
            zlabel(app.UIAxes_25, 'Z')
            app.Tab_7 = uitab(app.TabGroup8);
            app.Tab_7.Title = '7';
            app.UIAxes_26 = uiaxes(app.Tab_7);
            zlabel(app.UIAxes_26, 'Z')
            app.Tab_8 = uitab(app.TabGroup8);
            app.Tab_8.Title = '8';
            app.UIAxes_27 = uiaxes(app.Tab_8);
            zlabel(app.UIAxes_27, 'Z')
            app.Tab_9 = uitab(app.TabGroup8);
            app.Tab_9.Title = '9';
            app.UIAxes_28 = uiaxes(app.Tab_9);
            zlabel(app.UIAxes_28, 'Z')
            app.Tab_10 = uitab(app.TabGroup8);
            app.Tab_10.Title = '10';
            app.UIAxes_29 = uiaxes(app.Tab_10);
            zlabel(app.UIAxes_29, 'Z')
            app.MilitarySpecificationsTab = uitab(app.TabGroup5);
            app.MilitarySpecificationsTab.Title = 'Military Specifications';
            app.TabGroup9 = uitabgroup(app.MilitarySpecificationsTab);
            app.SPWnvszetaTab = uitab(app.TabGroup9);
            app.SPWnvszetaTab.Title = 'SP - Wn vs. zeta';
            app.UIAxes_30 = uiaxes(app.SPWnvszetaTab);
            title(app.UIAxes_30, 'Title')
            xlabel(app.UIAxes_30, 'X')
            ylabel(app.UIAxes_30, 'Y')
            zlabel(app.UIAxes_30, 'Z')
            app.SPWnvsdndalphaTab = uitab(app.TabGroup9);
            app.SPWnvsdndalphaTab.Title = 'SP - Wn vs. d(n)/d(alpha)';
            app.UIAxes_31 = uiaxes(app.SPWnvsdndalphaTab);
            title(app.UIAxes_31, 'Title')
            xlabel(app.UIAxes_31, 'X')
            ylabel(app.UIAxes_31, 'Y')
            zlabel(app.UIAxes_31, 'Z')
            app.DRTab = uitab(app.TabGroup9);
            app.DRTab.Title = 'DR';
            app.UIAxes_32 = uiaxes(app.DRTab);
            title(app.UIAxes_32, 'Title')
            xlabel(app.UIAxes_32, 'X')
            ylabel(app.UIAxes_32, 'Y')
            zlabel(app.UIAxes_32, 'Z')
            app.OptionsTab_2 = uitab(app.TabGroup);
            app.OptionsTab_2.Title = 'Options';
            app.Includethecontroldesignerfromaero3000Label = uilabel(app.OptionsTab_2);
            app.Includethecontroldesignerfromaero3000Label.Text = 'Include the control designer from aero3000';
            app.CompareResultsLabel = uilabel(app.OptionsTab_2);
            app.CompareResultsLabel.Text = 'Compare Results';
            app.RealTimevisualsimulationLabel = uilabel(app.OptionsTab_2);
            app.RealTimevisualsimulationLabel.Text = 'Real Time visual simulation';
            app.LoadSaveAllDataexceptresultsLabel = uilabel(app.OptionsTab_2);
            app.LoadSaveAllDataexceptresultsLabel.Text = 'Load & Save All Data (except results)';
            app.FileDropDownLabel = uilabel(app.OptionsTab_2);
            app.FileDropDownLabel.Text = 'File';
            app.FileDropDown = uidropdown(app.OptionsTab_2);
            app.FileDropDown.Items = {};
            app.FileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FileDropDownOpening, true);
            app.FileDropDown.Value = {};
            app.LoadFileButton = uibutton(app.OptionsTab_2, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.SaveFileButton = uibutton(app.OptionsTab_2, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Text = 'Save File';
            app.FileNameEditFieldLabel = uilabel(app.OptionsTab_2);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.OptionsTab_2, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end